classdef devfOLD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ButtonGroup               matlab.ui.container.ButtonGroup
        RefLobar                  matlab.ui.control.ToggleButton
        RefHammers                matlab.ui.control.ToggleButton
        RefLPBA40                 matlab.ui.control.ToggleButton
        RefBrainnetome            matlab.ui.control.ToggleButton
        RefDKT                    matlab.ui.control.ToggleButton
        RefAAL                    matlab.ui.control.ToggleButton
        Landmarks                 matlab.ui.control.ListBox
        AnatomicalLandmarksLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Montages                  matlab.ui.container.TabGroup
        Montage1010               matlab.ui.container.Tab
        Figure1010                matlab.ui.control.StateButton
        Fpz                       matlab.ui.control.Lamp
        AF7                       matlab.ui.control.Lamp
        AF3                       matlab.ui.control.Lamp
        AF4                       matlab.ui.control.Lamp
        AF8                       matlab.ui.control.Lamp
        F7                        matlab.ui.control.Lamp
        F3                        matlab.ui.control.Lamp
        Fz                        matlab.ui.control.Lamp
        F4                        matlab.ui.control.Lamp
        F8                        matlab.ui.control.Lamp
        FT9                       matlab.ui.control.Lamp
        FC5                       matlab.ui.control.Lamp
        FC1                       matlab.ui.control.Lamp
        FC2                       matlab.ui.control.Lamp
        FC6                       matlab.ui.control.Lamp
        FT10                      matlab.ui.control.Lamp
        T7                        matlab.ui.control.Lamp
        C3                        matlab.ui.control.Lamp
        Cz                        matlab.ui.control.Lamp
        C4                        matlab.ui.control.Lamp
        T8                        matlab.ui.control.Lamp
        TP9                       matlab.ui.control.Lamp
        CP5                       matlab.ui.control.Lamp
        CP1                       matlab.ui.control.Lamp
        CP2                       matlab.ui.control.Lamp
        CP6                       matlab.ui.control.Lamp
        TP10                      matlab.ui.control.Lamp
        P7                        matlab.ui.control.Lamp
        P3                        matlab.ui.control.Lamp
        Pz                        matlab.ui.control.Lamp
        P4                        matlab.ui.control.Lamp
        P8                        matlab.ui.control.Lamp
        PO9                       matlab.ui.control.Lamp
        O1                        matlab.ui.control.Lamp
        POz                       matlab.ui.control.Lamp
        O2                        matlab.ui.control.Lamp
        PO10                      matlab.ui.control.Lamp
        Iz                        matlab.ui.control.Lamp
        Fp1                       matlab.ui.control.Lamp
        Fp2                       matlab.ui.control.Lamp
        AFz                       matlab.ui.control.Lamp
        F9                        matlab.ui.control.Lamp
        F5                        matlab.ui.control.Lamp
        F1                        matlab.ui.control.Lamp
        F2                        matlab.ui.control.Lamp
        F6                        matlab.ui.control.Lamp
        F10                       matlab.ui.control.Lamp
        FT7                       matlab.ui.control.Lamp
        FC3                       matlab.ui.control.Lamp
        FCz                       matlab.ui.control.Lamp
        FC4                       matlab.ui.control.Lamp
        FT8                       matlab.ui.control.Lamp
        C5                        matlab.ui.control.Lamp
        C1                        matlab.ui.control.Lamp
        C2                        matlab.ui.control.Lamp
        C6                        matlab.ui.control.Lamp
        TP7                       matlab.ui.control.Lamp
        CP3                       matlab.ui.control.Lamp
        Cpz                       matlab.ui.control.Lamp
        CP4                       matlab.ui.control.Lamp
        TP8                       matlab.ui.control.Lamp
        P9                        matlab.ui.control.Lamp
        P5                        matlab.ui.control.Lamp
        P1                        matlab.ui.control.Lamp
        P2                        matlab.ui.control.Lamp
        P6                        matlab.ui.control.Lamp
        P10                       matlab.ui.control.Lamp
        PO7                       matlab.ui.control.Lamp
        PO3                       matlab.ui.control.Lamp
        PO4                       matlab.ui.control.Lamp
        PO8                       matlab.ui.control.Lamp
        Oz                        matlab.ui.control.Lamp
        I1                        matlab.ui.control.Lamp
        I2                        matlab.ui.control.Lamp
        HelpDisplay               matlab.ui.control.TextArea
        Summary                   matlab.ui.container.Tab
        TabGroup                  matlab.ui.container.TabGroup
        LandmarksTab              matlab.ui.container.Tab
        LabelDropDown             matlab.ui.control.Label
        SumLandmark               matlab.ui.control.DropDown
        SumResults                matlab.ui.control.Table
        ChannelsTab               matlab.ui.container.Tab
        TableChannels             matlab.ui.control.Table
        OptodessTab               matlab.ui.container.Tab
        TableOptodes              matlab.ui.control.Table
        Save                      matlab.ui.control.Button
        Load                      matlab.ui.control.Button
        Export                    matlab.ui.control.Button
        Slider                    matlab.ui.control.Slider
        SpecificityLabel          matlab.ui.control.Label
        MinCoverage               matlab.ui.control.Spinner
        ForceSym                  matlab.ui.control.CheckBox
        HelpAtlas                 matlab.ui.control.TextArea
        HelpLandmarks             matlab.ui.control.TextArea
        HelpCoverage              matlab.ui.control.TextArea
        HelpSymmetry              matlab.ui.control.TextArea
        HelpMontages              matlab.ui.control.TextArea
        HelpAbout                 matlab.ui.control.TextArea
        Help                      matlab.ui.control.StateButton
        Add                       matlab.ui.control.Button
        Logo1                     matlab.ui.control.Button
        Logo2                     matlab.ui.control.Button
        AgeDropDownLabel          matlab.ui.control.Label
        AgeDropDown               matlab.ui.control.DropDown
        AgespecifyLabel           matlab.ui.control.Label
        AgespecifyEditField       matlab.ui.control.EditField
        HelpAgeSpecify            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Lobar = {};
        Brainnetome = {};
        LONI = {};
        Hammers = {};
        Colin = {};
        SPM = {};
        DKT = {};
        AAL = {};
        chlabels = {};
        labels1010 = {};
        lndmrks = {};
        channels = [];
        sources = {};
        detectors = {};
        changed = [];
        meta = {};
    end
    
    methods (Access = private)
        
        function allinvisible(app)
            for i = 1:length(app.labels1010.sources)
                app.(genvarname(app.labels1010.sources{i})).Visible = 'off';
            end
            for i =1:length(app.labels1010.detectors)
                app.(genvarname(app.labels1010.detectors{i})).Visible = 'off';
            end
            %{
            for i = 1:length(app.labels1005.sources)
                app.(genvarname(app.labels1005.sources{i})).Visible = 'off';
                app.([genvarname(app.labels1005.sources{i}) '_2']).Visible = 'off';
            end
            for i =1:length(app.labels1005.detectors)
                app.(genvarname(app.labels1005.detectors{i})).Visible = 'off';
                app.([genvarname(app.labels1005.detectors{i}) '_2']).Visible = 'off';
            end
            %}
        end
        
        function updateDisplay(app)
            for z=1:max(length(app.sources),length(app.detectors))
                app.(genvarname(app.sources{min(z,length(app.sources))})).Visible = 'on';
                app.(genvarname(app.detectors{min(z,length(app.detectors))})).Visible = 'on';
                %{
                if strcmp(app.SumMontage.Value,'10-5')
                    app.([genvarname(app.sources{min(z,length(app.sources))}) '_2']).Visible = 'on';
                    app.([genvarname(app.detectors{min(z,length(app.detectors))}) '_2']).Visible = 'on';
                end
                %}
            end              
        end
        
        %XF: edit "summary" table function
        function updateTableChannels(app)
            
            changed = app.changed;
            
            if ~isempty(app.TableChannels.Data) && changed
                app.TableChannels.Data(1:size(app.TableChannels.Data,1),:) = []; %reset table contents
                app.changed = 0;
            end
            
            if ~isempty(app.channels) && changed
                
                %h = waitbar(0,'Please wait while channels table is updated...');
                
                selectedButton = app.ButtonGroup.SelectedObject;
                
                if app.meta.flag == 0
                    %template = app.(genvarname(selectedButton.Text)).template;
                    template = 'SPM';
                else
                    %template = selectedButton.Text(isstrprop(selectedButton.Text,'alpha'));
                    template = 'SPM';
                end
                
                count=1;
                for i=1:length(app.channels)
                    tbch(count,1:3) = [{i} app.chlabels(app.channels(i),1) app.chlabels(app.channels(i),2)];
                    
                    chcoord = round(app.(genvarname(template)).ch_coord(app.channels(i),:));
                    chdist = app.(genvarname(template)).chdist(app.channels(i));
                    if app.meta.flag < 2
                        %anatom = app.(genvarname(app.ButtonGroup.SelectedObject.Text)).anatom_label{app.channels(i)};
                        %acc = app.(genvarname(app.ButtonGroup.SelectedObject.Text)).accuracy{app.channels(i)};
                        anatom = app.(genvarname(app.ButtonGroup.SelectedObject.Text)).anatom_brain{app.channels(i)};
                        acc = app.(genvarname(app.ButtonGroup.SelectedObject.Text)).acc_brain{app.channels(i)};
                    else
                        anatom = app.meta.anatom_label{app.channels(i)};
                        acc = app.meta.accuracy{app.channels(i)};
                    end
                    
                    tbch(count,4:6) = [{chcoord(:,1)} {chcoord(:,2)} {chcoord(:,3)}];
                    
                    [acc,idx] = sort(acc,'descend');
                    anatom = anatom(idx);
                    
                    %in parcellation mode, display only landmarks with at least 5% of coverage (unless lower value chosen)
                    if app.meta.flag < 2
                        idx = acc >= min(5,app.MinCoverage.Value);
                        acc = acc(idx);
                        anatom = anatom(idx);
                    end
                    
                    for j=1:length(acc)
                        tbch(count,7:8) = [anatom{j} {acc(j)}];
                        count=count+1;
                    end
                    tbch(count,:) = repmat({''},1,8);
                    count=count+1;
                    %waitbar(i/length(app.channels),h);
                end
                
                app.TableChannels.Data = tbch;
                app.TableChannels.ColumnFormat = repmat({'numeric'},1,8);
                app.TableChannels.ColumnFormat{7} = 'char';
                
                if strcmp(app.TableChannels.ColumnWidth,'auto')
                    app.TableChannels.ColumnWidth = {45, 60, 60, 35, 35, 35, 'auto', 90};
                end
                
                app.changed = 0;
                %close(h);
                
            end
        end
        
        function updateTableOptodes(app)
            
            if ~isempty(app.TableOptodes.Data)
                app.TableOptodes.Data(1:size(app.TableOptodes.Data,1),:) = []; %reset table contents
            end
            
            if ~isempty(app.sources) && ~isempty(app.detectors)
                
                for i=1:length(app.sources)
                    tbop(i,1:2) = [{i} app.sources(i)];
                    tbop(i,3) = {sum(ismember(app.chlabels(app.channels,1),app.sources(i)))};
                end
                [tbop(1:i,3),idx] = sortrows(tbop(1:i,3),'descend');
                tbop(1:i,2) = tbop(idx,2);
                
                for j=1:length(app.detectors)
                    tbop(j,5:6) = [{j} app.detectors(j)];
                    tbop(j,7) = {sum(ismember(app.chlabels(app.channels,2),app.detectors(j)))};
                end
                [tbop(1:j,7),idx] = sortrows(tbop(1:j,7),'descend');
                tbop(1:j,6) = tbop(idx,6);
                
                app.TableOptodes.Data = tbop;
                app.TableOptodes.ColumnFormat = repmat({'numeric'},1,7);
                
                if strcmp(app.TableOptodes.ColumnWidth,'auto')
                    app.TableOptodes.ColumnWidth = {80, 80, 80, 55, 80, 80, 80};
                end
                
            end
        end
        
        
        function forceSymmetry(app)

            
            chn0 = app.chlabels(app.channels,:);
            chn = chn0;
            
            for i=1:size(chn0,1) %all channels
                chn = changeLabels(chn0, chn, i, 0);
                %ignore 10-5 mid line channels with both odd and even labels:
                if strcmp(chn(i,1),chn(i,2))
                    chn(i,:) = chn0(i,:);
                end
            end
            
            for i=1:size(chn,1)
                res = ismember(sum(ismember(chn,chn(i,:)),2),2); %find rows that corresponds to chn(i)
                if sum(res) == 1 %this means that only one channel was found (not symmetrical)
                    idx = find(res); %retrieve index of channel to be mirrored
                    chn0 = changeLabels(chn0, chn, idx, 1);
                    app.channels = [app.channels find(ismember(sum(ismember(app.chlabels,chn0(idx,:)),2),2))];
                end
            end
            
            %in case there is any repetition:
            [~,idx] = unique(app.channels);
            app.channels = app.channels(sort(idx));
            
            function chnX = changeLabels(chn0, chn, idx, flag)
                if flag
                    chnX = chn0;
                else
                    chnX = chn;
                end
                for j=1:2
                    lbl = char(chn0(idx,j)); %transform label cell to char
                    nidx = find(~isletter(lbl)); %find indexes of numbers in label
                    if ~isempty(nidx) %if there is any number (not middle line on 10-10)
                        lblnum = str2double(lbl(nidx)); %retrieve number from label
                        if ~mod(lblnum,2) %check if even (right hemisphere)
                            lblnum = lblnum-1; %equal to left hemisphere
                            lbl = writeLabels(lbl, nidx, lblnum);
                        elseif flag
                            lblnum = lblnum+1; %equal to right hemisphere
                            lbl = writeLabels(lbl, nidx, lblnum);
                        end
                        chnX(idx,j) = {lbl};
                    end
                end
                
                function lbl = writeLabels(lbl, nidx, lblnum)
                    if length(lbl(nidx)) == length(num2str(lblnum))
                        lbl(nidx) = num2str(lblnum);
                    elseif length(lbl(nidx)) > length(num2str(lblnum)) %original label number is two-digit
                        lbl(nidx(1)) = num2str(lblnum); %write one-digit number
                        lbl = regexprep(lbl,lbl(nidx(2)),''); %remove excedent digit from string
                    else %original label one-digit , new label two-digit
                        lbl = [lbl(1:nidx-1) num2str(lblnum) lbl(nidx+1:end)]; %insert two-digit in middle
                    end
                end
                
            end                       
            
        end
        
        function metaUpdate(app)
            
            h = waitbar(0,'Updating...');
            
            selectedButton = app.ButtonGroup.SelectedObject;

            app.meta.landmarks = {};
            app.meta.thres = [];
            app.meta.roi = {};
            for i=1:130
                app.meta.anatom_label{i} = {};
                app.meta.accuracy{i} = [];
            end
            app.Landmarks.Items = {};
            app.meta.landmarks = {};
            
            %template = selectedButton.Text(isstrprop(selectedButton.Text,'alpha'));
            template = 'SPM';
            chMNI = app.(genvarname(template)).ch;
            
            for i=1:length(app.meta.filename)
                
                [allchn, perc, perc_rel] = MetaAnalysis(app.meta.filename{i}, chMNI);
                
                [~,app.meta.landmarks{i},~] = fileparts(app.meta.filename{i});
                app.meta.roi{i}.landmark = app.meta.landmarks{i};
                app.meta.roi{i}.channels = allchn;
                app.meta.roi{i}.labels = app.chlabels(allchn,:);
                app.meta.roi{i}.perc = perc_rel;
                
                for k=1:length(allchn)
                    
                    % if one prefers to display the sensitivity, uncomment following lines:
                    
                    %                         if ~app.meta.add || isempty(app.meta.anatom_label{allchn(k)}) %initialize with outter brain sensitivities
                    %                             app.meta.anatom_label{allchn(k)}{1} = 'Scalp';
                    %                             app.meta.anatom_label{allchn(k)}{2} = 'Skull';
                    %                             app.meta.anatom_label{allchn(k)}{3} = 'CSF';
                    %                             app.meta.accuracy{allchn(k)}(1:3) = app.(genvarname(template)).sens(allchn(k),1:3);
                    %                         else
                    app.meta.anatom_label{allchn(k)}{min(i,end+1)} = {app.meta.landmarks{i}};
                    %                             app.meta.accuracy{allchn(k)}(end+1) = perc(k); %sensitivity
                    app.meta.accuracy{allchn(k)}(min(i,end+1)) = perc_rel(k); %coverage
                    %                         end
                    
                end
                
                app.Landmarks.Items{i} = app.meta.landmarks{i};
                
                app.meta.add = 0;
                
            end
            
            app.Landmarks.Items = app.meta.landmarks;
            app.Landmarks.Value = app.Landmarks.Items;
            LandmarksValueChanged(app);
            
            close(h);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            state = 'off';
            app.HelpAbout.Visible = state;
            app.HelpAtlas.Visible = state;
            app.HelpAgeSpecify.Visible = state;
            app.HelpLandmarks.Visible = state;
            app.HelpCoverage.Visible = state;
            app.HelpSymmetry.Visible = state;
            app.HelpMontages.Visible = state;
            %app.HelpMode.Visible = state;
            app.HelpDisplay.Visible = state;
            app.Logo1.Visible = state;
            app.Logo2.Visible = state;
            
            app.Add.Visible = 'off';
            
            %XF add: load default data
            agename = 'Default';
            
            %app.Lobar = load('Lobar1010.mat');
            %app.Brainnetome = load('Brainnetome1010.mat');
            %app.LONI = load('LPBA401010.mat');
            %app.Hammers = load('Hammers1010.mat');
            %app.DKT = load('DKT1010.mat');
            app.Lobar = load(['Lobar1010_' agename '.mat']);
            app.Brainnetome = load(['Brainnetome1010_' agename '.mat']);
            app.LONI = load(['LPBA401010_' agename '.mat']);
            app.Hammers = load(['Hammers1010_' agename '.mat']);
            app.DKT = load(['DKT1010_' agename '.mat']);
            app.AAL = load(['AAL1010_' agename '.mat']);
            
            %app.Colin = load('Colin1010.mat');
            app.SPM = load('SPM1010.mat');
            %XF: Use Colin as default, later change to age-specific template
            
            
            load('channels1010.mat'); app.chlabels = labels;
            
            app.labels1010 = load('labels1010.mat');
            %app.labels1005 = load('labels1005.mat');
            allinvisible(app);
            
            app.ButtonGroup.SelectedObject = app.RefLobar;
            
            app.MinCoverage.Value = 30;
            app.Slider.Value = app.MinCoverage.Value;
            
            app.ForceSym.Value = false;
            
            app.Montages.SelectedTab = app.Montage1010;
            
            app.SumResults.Data = {};
            app.TableChannels.Data = {};
            app.TableOptodes.Data = {};
            
            app.changed = 0;
            app.meta.flag = 0;
            app.meta.add = 0;
            app.meta.filename = {};
            app.meta.help = 1;
            
            ButtonGroupSelectionChanged(app);
            
            screenres = get(0,'screensize');
            appres = app.UIFigure.Position;
            app.UIFigure.Position = [(screenres(3)-appres(3))/2 (screenres(4)-appres(4))/2 865 641];
            
            msgbox({'Welcome to the Developmental fNIRS Optodes Location Decider (devfOLD)!';
                '';
                'This toolbox allows for optode arrangement based on participant age and anatomical landmarks.';
                '';
                'Please refer to following publications for detailed information:';
                '';
                'Zimeo Morais, Balardin, Sato (2018), "fNIRS Optodes Location Decider (fOLD):';
                'a toolbox for probe arrangement guided by brain regions-of-interest"';
                '';
                'Fu and Richards, "Age-Specific Design of fNIRS Channel Placement: devfOLD:';
                'The Developmental fNIRS Optodes Location Decider"';
                %                 '';
                %                 '';
                %                 'Please also refer to the publications related to the available atlases:';
                %                 '';
                %                 'Lobar: http://dx.doi.org/10.1016/j.neuroimage.2015.07.075';
                %                 '      http://dx.doi.org/10.1006/nimg.2001.0978';
                %                 '';
                %                 'Hammers: http://dx.doi.org/10.1016/j.jneumeth.2015.07.013';
                %                 '';
                %                 'Brainnetome: http://doi.org/10.1155/2000/421719';
                %                 '';
                %                 'Jülich: https://doi.org/10.1016/j.neuroimage.2004.12.034';
                %                 '        https://doi.org/10.1016/j.neuroimage.2006.04.204';
                %                 '        https://doi.org/10.1016/j.neuroimage.2007.03.060';
                %                 '';
                %                 'LONI: https://doi.org/10.1016/j.neuroimage.2007.09.031';
                %                 '';
                %                 '';
                %                 'fOLD is also based on following publicly available tools:'
                %                 '';
                %                 'NFRI toolbox (by Jichi Medical University): http://www.jichi.ac.jp/brainlab/tools.html';
                %                 '';
                %                 'Tools for NIfTI and ANALYZE image (by Jimmy Shen):'
                %                 'https://www.mathworks.com/matlabcentral/fileexchange/8797-tools-for-nifti-and-analyze-image';
                %                 '';
                %                 'iso2mesh (by Qianqian Fang): http://iso2mesh.sourceforge.net/cgi-bin/index.cgi';
                %                 '';
                %                 'Monte Carlo Extreme (by Qianqian Fang et al.): http://mcx.space/';
                %                 '';
                %                 'Mesh2EEG (by Giacometti et al.): http://dx.doi.org/10.1016/j.jneumeth.2014.04.020';
                %                 '';
                %                 'AtlasViewer (by Boas et al.): http://dx.doi.org/10.1117/1.NPh.2.2.020801';
                '';}, ...
                'Welcome!');
            
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.nii;*.nii.gz;*.img','NIfTI or ANALYZE (*.nii, *.img)'}, 'Please choose file name and location');
            if ~ischar(filename) || ~ischar(pathname)
                msgbox('Image file has not been loaded.','Alert');
                return;
            end
            
            app.meta.filename{end+1} = [pathname filename];
            app.meta.add = 1;
            
            allinvisible(app);
            
            if strcmp(app.SumMontage.Value,'10-10')
                app.Montages.SelectedTab = app.Montage1010;
            else
                app.Montages.SelectedTab = app.MontageEEG64;
            end
            
            metaUpdate(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            %XF add (failed)
            %atlasname = convertStringsToChars(selectedButton.Text);
            
            allinvisible(app);
            
            if app.meta.flag > 0
                metaUpdate(app);
                
            else
                app.lndmrks = {};
                app.channels = {};
                app.detectors = {};
                app.sources = {};
                
                app.SumLandmark.Items = {};
                app.SumLandmark.Value = {};
                
                app.Landmarks.Items = (app.(genvarname(selectedButton.Text)).landmarks);
                app.Landmarks.Value = {};
                
                if ~isempty(app.SumResults.Data)
                    app.SumResults.Data = [];
                end
                if ~isempty(app.TableChannels.Data)
                    app.TableChannels.Data(1:size(app.TableChannels.Data,1),:) = [];
                end
                if ~isempty(app.TableOptodes.Data)
                    app.TableOptodes.Data(1:size(app.TableOptodes.Data,1),:) = [];
                end
            end
            
            %XF add - e.g. when select LONI, fill age-specific properties
            %with age-specific LONI data
             %{
            if strcmp(selectedButton.Text, 'LONI')
                app.Default = load('LPBA401010.mat');
                app.Age_3Months = load('LPBA401010_Age_3Months.mat');
                app.Age_6Months = load('LPBA401010_Age_6Months.mat');
                app.Age_Adults = load('LPBA401010_Age_Adults.mat');
            end
         
            app.Default = (app.(genvarname(selectedButton.Text)));
                app.Age_3Months = (app.(genvarname(selectedButton.Text)));
                app.Age_6Months = (app.(genvarname(selectedButton.Text)));
                app.Age_Adults =(app.(genvarname(selectedButton.Text)));
            %}
        end

        % Value changed function: AgeDropDown
        function AgeDropDownSelectionChanged(app, event)
            value = app.AgeDropDown.Value;
            
            %XF add
            agename = convertStringsToChars(value);
            app.LONI = load(['LPBA401010_' agename '.mat']);
            app.Lobar = load(['Lobar1010_' agename '.mat']);
            app.Hammers = load(['Hammers1010_' agename '.mat']);
            app.Brainnetome = load(['Brainnetome1010_' agename '.mat']);
            app.DKT = load(['DKT1010_' agename '.mat']);
            app.AAL = load(['AAL1010_' agename '.mat']);
            app.SPM = load([agename 'MCX1010.mat']);
            
            %index Atlas selection
            selectedButton1 = app.ButtonGroup.SelectedObject;
            
            allinvisible(app);
            
            if app.meta.flag > 0
                metaUpdate(app);
                
            else
                app.lndmrks = {};
                app.channels = {};
                app.detectors = {};
                app.sources = {};
                
                app.SumLandmark.Items = {};
                app.SumLandmark.Value = {};
                
                app.Landmarks.Items = (app.(genvarname(selectedButton1.Text)).landmarks);
                app.Landmarks.Value = {};
                
                if ~isempty(app.SumResults.Data)
                    app.SumResults.Data = [];
                end
                if ~isempty(app.TableChannels.Data)
                    app.TableChannels.Data(1:size(app.TableChannels.Data,1),:) = [];
                end
                if ~isempty(app.TableOptodes.Data)
                    app.TableOptodes.Data(1:size(app.TableOptodes.Data,1),:) = [];
                end
            end
            
        end

        % Value changed function: AgespecifyEditField
        function AgeSpecifyValueChanged(app, event)
            value = app.AgespecifyEditField.Value;
            value1 = app.AgeDropDown.Value;
            
            agetemp = convertStringsToChars(value);
            agetemp1 = convertStringsToChars(value1);
            
            if strcmp(agetemp1,'Default') && ~isempty(agetemp)
                agename = agetemp;
            else
                agename = agetemp1;
            end
            
            app.LONI = load(['LPBA401010_' agename '.mat']);
            app.Lobar = load(['Lobar1010_' agename '.mat']);
            app.Hammers = load(['Hammers1010_' agename '.mat']);
            app.Brainnetome = load(['Brainnetome1010_' agename '.mat']);
            app.DKT = load(['DKT1010_' agename '.mat']);
            app.AAL = load(['AAL1010_' agename '.mat']);
            app.SPM = load([agename 'MCX1010.mat']);
            
            %index Atlas selection
            selectedButton1 = app.ButtonGroup.SelectedObject;
            
            allinvisible(app);
            
            if app.meta.flag > 0
                metaUpdate(app);
                
            else
                app.lndmrks = {};
                app.channels = {};
                app.detectors = {};
                app.sources = {};
                
                app.SumLandmark.Items = {};
                app.SumLandmark.Value = {};
                
                app.Landmarks.Items = (app.(genvarname(selectedButton1.Text)).landmarks);
                app.Landmarks.Value = {};
                
                if ~isempty(app.SumResults.Data)
                    app.SumResults.Data = [];
                end
                if ~isempty(app.TableChannels.Data)
                    app.TableChannels.Data(1:size(app.TableChannels.Data,1),:) = [];
                end
                if ~isempty(app.TableOptodes.Data)
                    app.TableOptodes.Data(1:size(app.TableOptodes.Data,1),:) = [];
                end
            end
            
        end

        % Value changing function: AgespecifyEditField
        function AgeSpecifyValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)

            mtype = questdlg('Please choose desired file format(s)', 'Export Format', ...
                'Excel and Text', 'Text', 'Excel', 'Excel and Text');
            
            if isempty(mtype)
                msgbox('File format was not chosen.','Alert');
                return;
            end
            
            switch mtype
                
                case 'Excel and Text'
                    flagexc = 1;
                    flagtxt = 1;
                case 'Excel'
                    flagexc = 1;
                    flagtxt = 0;
                case 'Text'
                    flagexc = 0;
                    flagtxt = 1;
            end
            
            if flagexc
                [filename0, pathname] = uiputfile('.xls','Please choose Excel file name and location','fNIRS-ChannelPlacement.xls');
                filename = [pathname filename0];
            else
                [filename0, pathname] = uiputfile('.txt','Please choose Text file name and location','fNIRS-ChannelPlacement.txt');
            end
            
            if ~ischar(filename0) || ~ischar(pathname)
                msgbox('Arrangement has not been exported.','Alert');
            else
                h = waitbar(0,'Please wait while your arrangement is being exported...');
                
                %write header information
                sheet = 1;
                ref = 1;
                
                 header = [{'Summary'};...
                    {'System: 10-10'};...
                    {['Brain Atlas: ' app.ButtonGroup.SelectedObject.Text]};...
                    {['#Landmarks: ' num2str(length(app.Landmarks.Value))]};...
                    {['Coverage (%): ' num2str(app.MinCoverage.Value)]};...
                    {['Force Symmetry: ' num2str(app.ForceSym.Value)]};...
                    {['#Sources: ' num2str(length(app.sources))]};...
                    {['#Detectors: ' num2str(length(app.detectors))]};...
                    {['#Channels: ' num2str(length(app.channels))]}];
                
                if flagexc
                    %xlswrite(filename,header,sheet,['A' num2str(ref)]);
                    xlwrite(filename,header,sheet,['A' num2str(ref)]);
                end
                
                if flagtxt
                    [~,fname,~] = fileparts(filename0);
                    fid = fopen([pathname fname '.txt'],'w');
                    
                    fprintf(fid,'[Header]\r\n');
                    for i=1:length(header)
                        fprintf(fid,'%s\r\n',header{i});
                    end
                end
                
                waitbar(1/4,h);
                
                %write summary table landmarks
                sheet = 2;
                ref = 1;
                
                if flagtxt
                    fprintf(fid,'\r\n[Landmarks]\r\n');
                end
                
                for i=1:length(app.SumLandmark.Items)
                    
                    if flagtxt
                        fprintf(fid,'%s\r\n',app.SumLandmark.Items{i});
                    end
                    
                    if flagexc
                        xlwrite(filename,app.SumLandmark.Items(i),sheet,['A' num2str(ref)]);
                        if isfield(app.lndmrks{i},'src')
                            ref = ref+1;
                            xlwrite(filename,[{'Source'} {'Detector'}],sheet,['B' num2str(ref)]);
                            xlwrite(filename,[app.lndmrks{i}.src' app.lndmrks{i}.det'],sheet,['B' num2str(ref+1)]);
                            
                            xlwrite(filename,{'Specificity (%)'},sheet,['D' num2str(ref)]);
                            xlwrite(filename,app.lndmrks{i}.cvg',sheet,['D' num2str(ref+1)]);
                            
                            xlwrite(filename,{'Distance (mm)'},sheet,['E' num2str(ref)]);
                            xlwrite(filename,app.lndmrks{i}.dis',sheet,['E' num2str(ref+1)]);
                            
                            xlwrite(filename,[{'X'} {'Y'} {'Z'}],sheet,['F' num2str(ref)]);
                            xlwrite(filename,app.lndmrks{i}.mni,sheet,['F' num2str(ref+1)]);
                            
                            ref = ref + length(app.lndmrks{i}.cvg) + 3;
                        else
                            ref = ref + 3;
                        end
                        
                    end
                    
                end
                
                waitbar(2/4,h);
                
                %write summary table channels
                sheet = 3;
                ref = 1;
                
                updateTableChannels(app);
                tabch = app.TableChannels.Data;
                
                if flagexc
                    xlwrite(filename,...
                        [{'Channel'} {'Source'} {'Detector'} {'X'} {'Y'} {'Z'} {'Landmark'} {'Specificity (%)'}],...
                        sheet,['A' num2str(ref)]);
                    
                    xlwrite(filename,tabch,sheet,['A' num2str(ref+1)]);
                end
                
                waitbar(3/4,h);
                
                tabop = app.TableOptodes.Data;
                
                if flagexc
                    %write summary table optodes
                    sheet = 4;
                    ref = 1;
                    
                    xlwrite(filename,...
                        [{'Source #'} {'Position'} {'#Channels'} {''} {'Detector #'} {'Position'} {'#Channels'}],...
                        sheet,['A' num2str(ref)]);
                    
                    xlwrite(filename,tabop,sheet,['A' num2str(ref+1)]);
                end
                
                if flagtxt
                    fprintf(fid,'\r\n[Sources]\r\n');
                    for i=1:size(tabop,1)
                        if ~isempty(tabop{i,2})
                            fprintf(fid,'%s',tabop{i,2});
                            if i < size(tabop,1)
                                if ~isempty(tabop{i+1,2})
                                    fprintf(fid,', ');
                                end
                            else
                                fprintf(fid,'\r\n');
                            end
                        else
                            fprintf(fid,'\r\n');
                        end
                    end
                    
                    fprintf(fid,'\r\n[Detectors]\r\n');
                    for i=1:size(tabop,1)
                        if ~isempty(tabop{i,6})
                            fprintf(fid,'%s',tabop{i,6});
                            if i < size(tabop,1)
                                if ~isempty(tabop{i+1,6})
                                    fprintf(fid,', ');
                                end
                            else
                                fprintf(fid,'\r\n');
                            end
                        else
                            fprintf(fid,'\r\n');
                        end
                    end
                    
                    fprintf(fid,'\r\n[Channels]\r\n');
                    count=0;
                    for i=1:size(tabch,1)
                        if ~isempty(tabch{i,1})
                            count=count+1;
                            fprintf(fid,'%d. %s-%s\r\n',count,tabch{i,2},tabch{i,3});
                        end
                    end
                    
                    fprintf(fid,'\r\n[Coordinates]\r\n');
                    count=0;
                    for i=1:size(tabch,1)
                        if ~isempty(tabch{i,1})
                            count=count+1;
                            fprintf(fid,'%d. (%d,%d,%d)\r\n',count,tabch{i,4},tabch{i,5},tabch{i,6});
                        end
                    end
                    
                    fprintf(fid,'\r\n[Specificity]\r\n');
                    count=0;
                    for i=1:size(tabch,1)
                        if ~isempty(tabch{i,1})
                            count=count+1;
                            fprintf(fid,'%d. %s (%.1f%%)',count,tabch{i,7},tabch{i,8});
                        elseif ~isempty(tabch{i,7})
                            fprintf(fid,', %s (%.1f%%)',tabch{i,7},tabch{i,8});
                        else
                            fprintf(fid,'\r\n');
                        end
                    end
                    
                    fclose(fid);
                end
                
                waitbar(4/4,h);
                
                if flagexc && flagtxt
                    msgbox(['Arrangement saved as ' filename0 ' and ' fname '.txt under: ' pathname],'Arrangement exported');
                elseif flagexc
                    msgbox(['Arrangement saved as ' filename0 ' under: ' pathname],'Arrangement exported');
                else
                    msgbox(['Arrangement saved as ' fname '.txt under: ' pathname],'Arrangement exported');
                end
                
                close(h);
            end
        end

        % Value changed function: ForceSym
        function ForceSymValueChanged(app, event)
            value = app.ForceSym.Value;
            if ~isempty(app.channels)
                
                if value
                    
                    forceSymmetry(app);
                    
                    app.sources = unique(app.chlabels(app.channels,1));
                    app.detectors = unique(app.chlabels(app.channels,2));
                    
                    updateDisplay(app);
                    updateTableOptodes(app);
                    
                    app.changed = 1; %flag to update the table when it is displayed
                    if app.Montages.SelectedTab == app.Summary && app.TabGroup.SelectedTab == app.ChannelsTab
                        updateTableChannels(app); %this takes a bit longer, so only update if it is displayed
                    end
                    
                else
                    LandmarksValueChanged(app);
                end
                
            end
        end

        % Value changed function: Help
        function HelpValueChanged(app, event)
            value = app.Help.Value;
            if value
                state = 'on';
            else
                state = 'off';
            end
            app.HelpAbout.Visible = state;
            app.HelpAtlas.Visible = state;
            app.HelpAgeSpecify.Visible = state;
            app.HelpLandmarks.Visible = state;
            app.HelpCoverage.Visible = state;
            app.HelpSymmetry.Visible = state;
            app.HelpMontages.Visible = state;
            %app.HelpMode.Visible = state;
            app.HelpDisplay.Visible = state;
            app.Logo1.Visible = state;
            app.Logo2.Visible = state;
        end

        % Value changed function: Landmarks
        function LandmarksValueChanged(app, event)
            allinvisible(app);
            app.SumLandmark.Items = {};
            
            value = app.Landmarks.Value;
            
            %ch_coord = app.MNI.ch_coord;
            
            selectedButton = app.ButtonGroup.SelectedObject;
            %XF add: template is dependent on age. Change app.Colin content
            %base on age selection
            
            if app.meta.flag == 0
                landmarks = (app.(genvarname(selectedButton.Text)).landmarks);
                roi = app.(genvarname(selectedButton.Text)).roi;
                %template = app.(genvarname(selectedButton.Text)).template;
                template = 'SPM';
            else
                landmarks = app.meta.landmarks;
                roi = app.meta.roi;
                %template = selectedButton.Text(isstrprop(selectedButton.Text,'alpha'));
                template = 'SPM';
            end
            
            ch_coord = round(app.(genvarname(template)).ch_coord); 
            ch_dist = app.(genvarname(template)).chdist;
            
            thres = app.MinCoverage.Value;
            
            idxL = find(ismember(landmarks,value));
            if ~isempty(idxL)
                app.lndmrks = {};
                app.channels = [];
                app.sources = {};
                app.detectors = {};
                
                for i = 1:length(idxL)
                    
                    app.lndmrks{i}.name = app.Landmarks.Items{idxL(i)};
                    app.SumLandmark.Items{i} = app.lndmrks{i}.name;
                    
                    idxR = find(roi{idxL(i)}.perc >= thres);
                    
                    if ~isempty(idxR)
                        
                        app.lndmrks{i}.src{length(idxR)} = {};
                        app.lndmrks{i}.det{length(idxR)} = {};
                        app.lndmrks{i}.chn{length(idxR),2} = {};
                        app.lndmrks{i}.cvg{length(idxR)} = {};
                        app.lndmrks{i}.dis{length(idxR)} = {};
                        app.lndmrks{i}.mni{length(idxR),3} = {};
                        
                        newchn = roi{idxL(i)}.channels(idxR);
                        addchn = ~ismember(newchn,app.channels);
                        app.channels = [app.channels newchn(addchn)];
                        %app.sources = [app.sources; roi{idxL(i)}.labels(~ismember(roi{idxL(i)}.labels(idxR,1),app.sources),1)];
                        %app.detectors = [app.detectors; roi{idxL(i)}.labels(~ismember(roi{idxL(i)}.labels(idxR,2),app.detectors),2)];
                        
                        for j = 1:length(idxR)
                            app.lndmrks{i}.src{j} = roi{idxL(i)}.labels{idxR(j),1};
                            app.lndmrks{i}.det{j} = roi{idxL(i)}.labels{idxR(j),2};
                            app.lndmrks{i}.chn(j,:) = roi{idxL(i)}.labels(idxR(j),:);
                            app.lndmrks{i}.cvg{j} = roi{idxL(i)}.perc(idxR(j));
                            app.lndmrks{i}.dis{j} = round(ch_dist(roi{idxL(i)}.channels(idxR(j))));
                            for k = 1:3
                                app.lndmrks{i}.mni{j,k} = ch_coord(roi{idxL(i)}.channels(idxR(j)),k);
                            end
                        end
                    end
                    
                end
                
                if app.ForceSym.Value
                    forceSymmetry(app);
                end
                
                app.sources = unique(app.chlabels(app.channels,1));
                app.detectors = unique(app.chlabels(app.channels,2));
                
                updateDisplay(app);
                updateTableOptodes(app);
                
                app.changed = 1; %flag to update the table when it is displayed
                if app.Montages.SelectedTab == app.Summary && app.TabGroup.SelectedTab == app.ChannelsTab
                    updateTableChannels(app); %this takes a bit longer, so only update if it is displayed
                end
                
                app.SumLandmark.Value = app.SumLandmark.Items{1};
                SumLandmarkValueChanged(app);
            end
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.mat','devfOLD Files (*.mat)'},'Please choose file name and location');
            if ~ischar(filename) || ~ischar(pathname)
                msgbox('Positions have not been loaded.','Alert');
                return;
            else
                load([pathname filename]);
            end
            
            allinvisible(app);
            
            if strcmp(montage.SelectedMontage,'10-10')
                app.Montages.SelectedTab = app.Montage1010;
            else
                app.Montages.SelectedTab = app.MontageEEG64;
            end
            
            ButtonGroupSelectionChanged(app);
            MontagesSelectionChanged(app);
            
            app.ForceSym.Value = montage.ForceSym;
            
            app.channels = montage.channels;
            app.sources = montage.sources;
            app.detectors = montage.detectors;
            
            app.meta = montage.meta;
            if montage.meta.flag == 0
                app.MetaButton.Text = 'Mode: Parcellation';
            else
                app.MetaButton.Text = 'Mode: Image Mask';
            end
            
            if montage.meta.flag < 2
                app.RefLobar.Text = 'Lobar';
                app.RefHammers.Visible = 'on';
                app.RefLPBA40.Visible = 'on';
                app.RefBrainnetome.Visible = 'on';
                app.RefDKT.Visible = 'on';
                app.RefAAL.Visible = 'on';
                app.Add.Visible = 'off';
            else
                app.RefLobar.Text = 'Lobar';
                app.RefHammers.Text = 'Hammers';
                app.RefLPBA40.Visible = 'off';
                app.RefBrainnetome.Visible = 'off';
                app.RefDKT.Visible = 'off';
                app.RefAAL.Visible = 'off';
                app.Add.Visible = 'on';
            end
            
            app.lndmrks = montage.landmarks;
            app.(genvarname(['Ref' montage.SelectedRef])).Value = 1;
            
            app.Landmarks.Items = (app.(genvarname(app.ButtonGroup.SelectedObject.Text)).landmarks);
            app.Landmarks.Value = montage.SelectedLnd;
            
            app.Slider.Value = montage.MinCoverage;
            app.SumLandmark.Items = montage.SummaryItems;
            
            value = app.Slider.Value;
            app.MinCoverage.Value = round(value);
            
            if ~isempty(app.SumLandmark.Items)
                app.SumLandmark.Value = app.SumLandmark.Items{1};
                SumLandmarkValueChanged(app);
            end
            
            updateDisplay(app);
            updateTableOptodes(app);
            app.changed = 1;
            
        end

        % Value changed function: MinCoverage
        function MinCoverageValueChanged(app, event)
            value = app.MinCoverage.Value;
            app.Slider.Value = value;
            LandmarksValueChanged(app);
        end

        % Value changing function: MinCoverage
        function MinCoverageValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Selection change function: Montages
        function MontagesSelectionChanged(app, event)
            %{
            selectedTab = app.Montages.SelectedTab;
            if ~strcmp(selectedTab.Title,'Summary')
                if strcmp(selectedTab.Title,'10-10')
                    %if strcmp(app.SumMontage.Value,'10-5')
                        app.Lobar = load('Lobar1010.mat');
                        app.Brainnetome = load('Brainnetome1010.mat');
                        app.LONI = load('LPBA401010.mat');
                        app.Hammers = load('Hammers1010.mat');
                        app.DKT = load('DKT1010.mat');
                        app.AAL = load('AAL1010_Default.mat');
                        app.Colin = load('Colin1010.mat');
                        %app.SPM = load('SPM1010.mat');
                        load('channels1010.mat'); app.chlabels = labels;
                        %app.SumMontage.Value = '10-10';
                        if app.meta.flag > 0
                            metaUpdate(app);
                        else
                            app.Landmarks.Items = (app.(genvarname(app.ButtonGroup.SelectedObject.Text)).landmarks);
                            app.Landmarks.Value = app.Landmarks.Value(ismember(app.Landmarks.Value,app.Landmarks.Items));
                            LandmarksValueChanged(app);
                        end
                        %ButtonGroupSelectionChanged(app);
                    %end %end app.SumMontage.Value,'10-5'
                    %{
                else
                    if strcmp(app.SumMontage.Value,'10-10')
                        app.Lobar = load('Lobar1005.mat');
                        app.Brainnetome = load('Brainnetome1005.mat');
                        app.LONI = load('LPBA401005.mat');
                        app.Hammers = load('Hammers1005.mat');
                        app.DKT = load('DKT1005.mat');
                        app.AAL = load('AAL21005.mat');
                        app.Colin = load('Colin1005.mat');
                        %app.SPM = load('SPM1005.mat');
                        load('channels1005.mat'); app.chlabels = labels;
                        app.SumMontage.Value = '10-5';
                        if app.meta.flag > 0
                            metaUpdate(app);
                        else
                            app.Landmarks.Items = (app.(genvarname(app.ButtonGroup.SelectedObject.Text)).landmarks);
                            app.Landmarks.Value = app.Landmarks.Value(ismember(app.Landmarks.Value,app.Landmarks.Items));
                            LandmarksValueChanged(app);
                        end
                        %ButtonGroupSelectionChanged(app);
                    end
                    %}
                end  %end selectedTab.Title,'10-10'
            else
                if app.TabGroup.SelectedTab == app.ChannelsTab
                    updateTableChannels(app);
                end
            end
            %}
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            montage.landmarks = app.lndmrks;
            montage.sources = app.sources;
            montage.detectors = app.detectors;
            montage.channels = app.channels;
            %montage.SelectedMontage = app.SumMontage.Value;
            montage.SelectedRef = app.ButtonGroup.SelectedObject.Text;
            montage.SelectedLnd = app.Landmarks.Value;
            montage.MinCoverage = app.Slider.Value;
            montage.ForceSym = app.ForceSym.Value;
            montage.SummaryItems = app.SumLandmark.Items;
            montage.meta = app.meta;
            
            [filename, pathname] = uiputfile('.mat','Please choose file name and location','Montage.mat');
            if ~ischar(filename) || ~ischar(pathname)
                msgbox('Montage has not been saved.','Alert');
            else
                save([pathname filename],'montage');
                msgbox(['Montage saved as ' filename ' under: ' pathname],'File saved');
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.MinCoverage.Value = round(value);
            LandmarksValueChanged(app);
        end

        % Value changed function: SumLandmark
        function SumLandmarkValueChanged(app, event)
            %value = app.SumLandmark.Value;
            value = ismember(app.SumLandmark.Items,app.SumLandmark.Value);
            if sum(value) > 0
                if isfield(app.lndmrks{value},'src')
                    app.SumResults.Data = [app.lndmrks{value}.src' app.lndmrks{value}.det' app.lndmrks{value}.cvg' ...
                        app.lndmrks{value}.dis' app.lndmrks{value}.mni(:,1) app.lndmrks{value}.mni(:,2) app.lndmrks{value}.mni(:,3)];
                else
                    app.SumResults.Data = {};
                end
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.ChannelsTab
                updateTableChannels(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'devfOLD v1.1';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Brain Atlas';
            app.RefLobar = uitogglebutton(app.ButtonGroup);
            app.RefLobar.Text = 'Lobar';
            app.RefLobar.Value = true;
            app.RefHammers = uitogglebutton(app.ButtonGroup);
            app.RefHammers.Text = 'Hammers';
            app.RefLPBA40 = uitogglebutton(app.ButtonGroup);
            app.RefLPBA40.Text = 'Brainnetome';
            app.RefBrainnetome = uitogglebutton(app.ButtonGroup);
            app.RefBrainnetome.Text = 'DKT';
            app.RefDKT = uitogglebutton(app.ButtonGroup);
            app.RefDKT.Text = 'LONI';
            app.RefAAL = uitogglebutton(app.ButtonGroup);
            app.RefAAL.Text = 'AAL';
            app.Landmarks = uilistbox(app.UIFigure);
            app.Landmarks.Items = {'Frontal_Sup_L', 'Frontal_Sup_Orb_L', 'Frontal_Sup_Medial_L', 'Frontal_Mid_Orb_L', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6'};
            app.Landmarks.Multiselect = 'on';
            app.Landmarks.ValueChangedFcn = createCallbackFcn(app, @LandmarksValueChanged, true);
            app.Landmarks.Value = {'Frontal_Sup_L'};
            app.AnatomicalLandmarksLabel = uilabel(app.UIFigure);
            app.AnatomicalLandmarksLabel.Text = 'Anatomical Landmarks';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Developmental fNIRS Optodes'' Location Decider';
            app.Montages = uitabgroup(app.UIFigure);
            app.Montages.SelectionChangedFcn = createCallbackFcn(app, @MontagesSelectionChanged, true);
            app.Montage1010 = uitab(app.Montages);
            app.Montage1010.Title = '10-10';
            app.Figure1010 = uibutton(app.Montage1010, 'state');
            app.Figure1010.Icon = '1010_HighRes.png';
            app.Figure1010.Text = '';
            app.Fpz = uilamp(app.Montage1010);
            app.Fpz.Enable = 'off';
            app.AF7 = uilamp(app.Montage1010);
            app.AF7.Enable = 'off';
            app.AF3 = uilamp(app.Montage1010);
            app.AF3.Enable = 'off';
            app.AF4 = uilamp(app.Montage1010);
            app.AF4.Enable = 'off';
            app.AF8 = uilamp(app.Montage1010);
            app.AF8.Enable = 'off';
            app.F7 = uilamp(app.Montage1010);
            app.F7.Enable = 'off';
            app.F3 = uilamp(app.Montage1010);
            app.F3.Enable = 'off';
            app.Fz = uilamp(app.Montage1010);
            app.Fz.Enable = 'off';
            app.F4 = uilamp(app.Montage1010);
            app.F4.Enable = 'off';
            app.F8 = uilamp(app.Montage1010);
            app.F8.Enable = 'off';
            app.FT9 = uilamp(app.Montage1010);
            app.FT9.Enable = 'off';
            app.FC5 = uilamp(app.Montage1010);
            app.FC5.Enable = 'off';
            app.FC1 = uilamp(app.Montage1010);
            app.FC1.Enable = 'off';
            app.FC2 = uilamp(app.Montage1010);
            app.FC2.Enable = 'off';
            app.FC6 = uilamp(app.Montage1010);
            app.FC6.Enable = 'off';
            app.FT10 = uilamp(app.Montage1010);
            app.FT10.Enable = 'off';
            app.T7 = uilamp(app.Montage1010);
            app.T7.Enable = 'off';
            app.C3 = uilamp(app.Montage1010);
            app.C3.Enable = 'off';
            app.Cz = uilamp(app.Montage1010);
            app.Cz.Enable = 'off';
            app.C4 = uilamp(app.Montage1010);
            app.C4.Enable = 'off';
            app.T8 = uilamp(app.Montage1010);
            app.T8.Enable = 'off';
            app.TP9 = uilamp(app.Montage1010);
            app.TP9.Enable = 'off';
            app.CP5 = uilamp(app.Montage1010);
            app.CP5.Enable = 'off';
            app.CP1 = uilamp(app.Montage1010);
            app.CP1.Enable = 'off';
            app.CP2 = uilamp(app.Montage1010);
            app.CP2.Enable = 'off';
            app.CP6 = uilamp(app.Montage1010);
            app.CP6.Enable = 'off';
            app.TP10 = uilamp(app.Montage1010);
            app.TP10.Enable = 'off';
            app.P7 = uilamp(app.Montage1010);
            app.P7.Enable = 'off';
            app.P3 = uilamp(app.Montage1010);
            app.P3.Enable = 'off';
            app.Pz = uilamp(app.Montage1010);
            app.Pz.Enable = 'off';
            app.P4 = uilamp(app.Montage1010);
            app.P4.Enable = 'off';
            app.P8 = uilamp(app.Montage1010);
            app.P8.Enable = 'off';
            app.PO9 = uilamp(app.Montage1010);
            app.PO9.Enable = 'off';
            app.O1 = uilamp(app.Montage1010);
            app.O1.Enable = 'off';
            app.POz = uilamp(app.Montage1010);
            app.POz.Enable = 'off';
            app.O2 = uilamp(app.Montage1010);
            app.O2.Enable = 'off';
            app.PO10 = uilamp(app.Montage1010);
            app.PO10.Enable = 'off';
            app.Iz = uilamp(app.Montage1010);
            app.Iz.Enable = 'off';
            app.Fp1 = uilamp(app.Montage1010);
            app.Fp1.Enable = 'off';
            app.Fp2 = uilamp(app.Montage1010);
            app.Fp2.Enable = 'off';
            app.AFz = uilamp(app.Montage1010);
            app.AFz.Enable = 'off';
            app.F9 = uilamp(app.Montage1010);
            app.F9.Enable = 'off';
            app.F5 = uilamp(app.Montage1010);
            app.F5.Enable = 'off';
            app.F1 = uilamp(app.Montage1010);
            app.F1.Enable = 'off';
            app.F2 = uilamp(app.Montage1010);
            app.F2.Enable = 'off';
            app.F6 = uilamp(app.Montage1010);
            app.F6.Enable = 'off';
            app.F10 = uilamp(app.Montage1010);
            app.F10.Enable = 'off';
            app.FT7 = uilamp(app.Montage1010);
            app.FT7.Enable = 'off';
            app.FC3 = uilamp(app.Montage1010);
            app.FC3.Enable = 'off';
            app.FCz = uilamp(app.Montage1010);
            app.FCz.Enable = 'off';
            app.FC4 = uilamp(app.Montage1010);
            app.FC4.Enable = 'off';
            app.FT8 = uilamp(app.Montage1010);
            app.FT8.Enable = 'off';
            app.C5 = uilamp(app.Montage1010);
            app.C5.Enable = 'off';
            app.C1 = uilamp(app.Montage1010);
            app.C1.Enable = 'off';
            app.C2 = uilamp(app.Montage1010);
            app.C2.Enable = 'off';
            app.C6 = uilamp(app.Montage1010);
            app.C6.Enable = 'off';
            app.TP7 = uilamp(app.Montage1010);
            app.TP7.Enable = 'off';
            app.CP3 = uilamp(app.Montage1010);
            app.CP3.Enable = 'off';
            app.Cpz = uilamp(app.Montage1010);
            app.Cpz.Enable = 'off';
            app.CP4 = uilamp(app.Montage1010);
            app.CP4.Enable = 'off';
            app.TP8 = uilamp(app.Montage1010);
            app.TP8.Enable = 'off';
            app.P9 = uilamp(app.Montage1010);
            app.P9.Enable = 'off';
            app.P5 = uilamp(app.Montage1010);
            app.P5.Enable = 'off';
            app.P1 = uilamp(app.Montage1010);
            app.P1.Enable = 'off';
            app.P2 = uilamp(app.Montage1010);
            app.P2.Enable = 'off';
            app.P6 = uilamp(app.Montage1010);
            app.P6.Enable = 'off';
            app.P10 = uilamp(app.Montage1010);
            app.P10.Enable = 'off';
            app.PO7 = uilamp(app.Montage1010);
            app.PO7.Enable = 'off';
            app.PO3 = uilamp(app.Montage1010);
            app.PO3.Enable = 'off';
            app.PO4 = uilamp(app.Montage1010);
            app.PO4.Enable = 'off';
            app.PO8 = uilamp(app.Montage1010);
            app.PO8.Enable = 'off';
            app.Oz = uilamp(app.Montage1010);
            app.Oz.Enable = 'off';
            app.I1 = uilamp(app.Montage1010);
            app.I1.Enable = 'off';
            app.I2 = uilamp(app.Montage1010);
            app.I2.Enable = 'off';
            app.HelpDisplay = uitextarea(app.Montage1010);
            app.HelpDisplay.Value = {'The layout display will update automatically once new anatomical landmarks are selected. '; ''; 'It will also be updated by changing the specificity threshold (%) or the force symmetry selection.'; ''; 'Sources and detectors positions are pre-defined. '; ''; 'Only source-detector channel(s) with specificity greater than the cut-off % are displayed.'; ''; 'Channels information (coordinates in the age-appropriate average template space, anatomical landmarks and specificity, etc.) are available under Summary and can be saved in Excel format (*.xls) and text files (*.txt) by clicking on Export.'; ''; 'fNIRS optodes locations can be saved and loaded.'};
            app.Summary = uitab(app.Montages);
            app.Summary.Title = 'Summary';
            app.TabGroup = uitabgroup(app.Summary);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LandmarksTab = uitab(app.TabGroup);
            app.LandmarksTab.Title = 'Landmarks';
            app.LabelDropDown = uilabel(app.LandmarksTab);
            app.LabelDropDown.Text = 'Landmark';
            app.SumLandmark = uidropdown(app.LandmarksTab);
            app.SumLandmark.ValueChangedFcn = createCallbackFcn(app, @SumLandmarkValueChanged, true);
            app.SumResults = uitable(app.LandmarksTab);
            app.SumResults.ColumnName = {'Source'; 'Detector'; 'Specificity (%)'; 'Distance (mm)'; 'X'; 'Y'; 'Z'};
            app.SumResults.RowName = {};
            app.ChannelsTab = uitab(app.TabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.TableChannels = uitable(app.ChannelsTab);
            app.TableChannels.ColumnName = {'#Ch.'; 'Source'; 'Detector'; 'X'; 'Y'; 'Z'; 'Landmark'; 'Specificity (%)'};
            app.TableChannels.RowName = {};
            app.OptodessTab = uitab(app.TabGroup);
            app.OptodessTab.Title = 'Sources and Detectors';
            app.TableOptodes = uitable(app.OptodessTab);
            app.TableOptodes.ColumnName = {'Source #'; 'Position'; '#Channels'; '-'; 'Detector #'; 'Position'; '#Channels'};
            app.TableOptodes.RowName = {};
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save';
            app.Load = uibutton(app.UIFigure, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.Export = uibutton(app.UIFigure, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = 'Export';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 50;
            app.SpecificityLabel = uilabel(app.UIFigure);
            app.SpecificityLabel.Text = 'Specificity (%)';
            app.MinCoverage = uispinner(app.UIFigure);
            app.MinCoverage.ValueChangingFcn = createCallbackFcn(app, @MinCoverageValueChanging, true);
            app.MinCoverage.Limits = [0 100];
            app.MinCoverage.ValueDisplayFormat = '%.0f';
            app.MinCoverage.ValueChangedFcn = createCallbackFcn(app, @MinCoverageValueChanged, true);
            app.MinCoverage.Value = 50;
            app.ForceSym = uicheckbox(app.UIFigure);
            app.ForceSym.ValueChangedFcn = createCallbackFcn(app, @ForceSymValueChanged, true);
            app.ForceSym.Text = 'Force Symmetry';
            app.HelpAtlas = uitextarea(app.UIFigure);
            app.HelpAtlas.Value = {'Six atlases are available for probabilistic labeling: Lobar, Hammers, LONI, Brainnetome, FreeSurfer DKT and AAL. '; ''; 'Please refer to and cite the corresponding paper(s).'};
            app.HelpLandmarks = uitextarea(app.UIFigure);
            app.HelpLandmarks.Value = {'List of anatomical landmarks provided by the chosen "Brain Atlas" within the chosen layout (i.e. 10-10 or 10-5 system). '; ''; 'Click on the desired landmark to generate the optodes positions. Select multiple landmarks by holding CTRL while clicking.'};
            app.HelpCoverage = uitextarea(app.UIFigure);
            app.HelpCoverage.Value = {'This threshold defines the minimum channel-to-ROI specificity in order to be included in the layout.'};
            app.HelpSymmetry = uitextarea(app.UIFigure);
            app.HelpSymmetry.Value = {'This allows to force optodes symmetry between left and right hemispheres.'};
            app.HelpMontages = uitextarea(app.UIFigure);
            app.HelpMontages.Value = {'Optode layout is displayed on 10-10 system. ''Summary'' provides look-up tables.'};
            app.HelpAbout = uitextarea(app.UIFigure);
            app.HelpAbout.Value = {'Developmental fNIRS Optodes'' Location Decider v1.1'; ''; 'Version 1, Revision 1, Build 1, 2020-12-01'; ''; 'Xiaoxue Fu & John E. Richards'; ''; 'University of South Carolina, USA;'; 'NIRx Medizintechnik GmbH, Germany'};
            app.Help = uibutton(app.UIFigure, 'state');
            app.Help.ValueChangedFcn = createCallbackFcn(app, @HelpValueChanged, true);
            app.Help.Text = 'Help';
            app.Add = uibutton(app.UIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = '+';
            app.Logo1 = uibutton(app.UIFigure, 'push');
            app.Logo1.Icon = 'UofSC_TreeGate_RGB_W.png';
            app.Logo1.Text = '';
            app.Logo2 = uibutton(app.UIFigure, 'push');
            app.Logo2.Icon = 'Logo_NIRx.PNG';
            app.Logo2.Text = '';
            app.AgeDropDownLabel = uilabel(app.UIFigure);
            app.AgeDropDownLabel.Text = 'Age';
            app.AgeDropDown = uidropdown(app.UIFigure);
            app.AgeDropDown.Items = {'Default', '3Months', '6Months', 'Adults'};
            app.AgeDropDown.ValueChangedFcn = createCallbackFcn(app, @AgeDropDownSelectionChanged, true);
            app.AgeDropDown.Value = 'Default';
            app.AgespecifyLabel = uilabel(app.UIFigure);
            app.AgespecifyLabel.Text = 'Age(specify)';
            app.AgespecifyEditField = uieditfield(app.UIFigure, 'text');
            app.AgespecifyEditField.ValueChangedFcn = createCallbackFcn(app, @AgeSpecifyValueChanged, true);
            app.AgespecifyEditField.ValueChangingFcn = createCallbackFcn(app, @AgeSpecifyValueChanging, true);
            app.HelpAgeSpecify = uitextarea(app.UIFigure);
            app.HelpAgeSpecify.Value = {'Please specicy Age if the option is not provided. Use the format as displayed in the Age dropdown list: e.g. 3Months'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = devfOLD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end